classdef DefendTheNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ShowNullclinesCheckBox        matlab.ui.control.CheckBox
        SimulationParametersPanel     matlab.ui.container.Panel
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        PresetScenariosDropDown       matlab.ui.control.DropDown
        PresetScenariosDropDownLabel  matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        AttackerBurnoutRateSlider_2   matlab.ui.control.Slider
        AttackerBurnoutRateSlider_2Label  matlab.ui.control.Label
        DefenderGrowthRateSlider_2    matlab.ui.control.Slider
        DefenderGrowthRateSlider_2Label  matlab.ui.control.Label
        RunSimulationButton           matlab.ui.control.Button
        DifficultyDropDown            matlab.ui.control.DropDown
        DifficultyDropDownLabel       matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        OutcomeSummaryPanel           matlab.ui.container.Panel
        OutcomeSummaryLabel           matlab.ui.control.Label
        AttackerStatsLabel            matlab.ui.control.Label
        DefenderStatsLabel            matlab.ui.control.Label
        LearnMoreButton               matlab.ui.control.Button
        NetworkSecureLabel            matlab.ui.control.Label
        NetworkHealthLamp             matlab.ui.control.Lamp
        NetworkHealthLampLabel        matlab.ui.control.Label
        TimeDisplayLabel              matlab.ui.control.Label
        AttackersGauge                matlab.ui.control.SemicircularGauge
        AttackersGaugeLabel           matlab.ui.control.Label
        DefendersGauge                matlab.ui.control.SemicircularGauge
        DefendersGaugeLabel           matlab.ui.control.Label
        BattleAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        NullclineXLine          % Line for dy/dt = 0
        NullclineYLine          % Line for dx/dt = 0
        EquilibriumMarker       % Equilibrium point marker
        NullclinePopupText      % Text annotation near equilibrium
    end
    
    methods (Access = private)
        
        function drawNullclines(app, alpha, beta, gamma, delta)
            % Delete previous lines and markers if they exist
            if isgraphics(app.NullclineXLine), delete(app.NullclineXLine); end
            if isgraphics(app.NullclineYLine), delete(app.NullclineYLine); end
            if isgraphics(app.EquilibriumMarker), delete(app.EquilibriumMarker); end
            if isgraphics(app.NullclinePopupText), delete(app.NullclinePopupText); end
    
            % Calculate nullcline positions
            y_null = alpha / beta;
            x_null = gamma / delta;
    
            x_range = app.BattleAxes.XLim;
            y_range = app.BattleAxes.YLim;
    
            % Plot nullclines
            app.NullclineXLine = plot(app.BattleAxes, x_range, [y_null y_null], ...
                '--', 'Color', [0.2 0.6 0.2], 'LineWidth', 1.5, 'DisplayName', 'dy/dt = 0');
            
            app.NullclineYLine = plot(app.BattleAxes, [x_null x_null], y_range, ...
                '--', 'Color', [0.3 0.3 0.8], 'LineWidth', 1.5, 'DisplayName', 'dx/dt = 0');
    
            % Plot equilibrium point
            app.EquilibriumMarker = plot(app.BattleAxes, x_null, y_null, ...
                'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 7, 'DisplayName', 'Equilibrium');
    
            % Add popup label near equilibrium point
            app.NullclinePopupText = text(app.BattleAxes, x_null + 1, y_null + 1, ...
                'Equilibrium Point', 'FontSize', 9, 'BackgroundColor', 'w', ...
                'Margin', 4, 'EdgeColor', [0.4 0.4 0.4]);
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % === INITIALIZE NULLCLINE OBJECTS ===
            app.NullclineXLine = [];
            app.NullclineYLine = [];
            app.EquilibriumMarker = [];
            app.NullclinePopupText = [];
        
            % === DEFAULT CHECKBOX ===
            if isprop(app, 'ShowNullclinesCheckBox')
                app.ShowNullclinesCheckBox.Value = false;
            end
        
            % === TEXT FIELDS INITIAL STATE ===
            app.OutcomeSummaryLabel.Text = '';
            app.NetworkSecureLabel.Text = '';
            app.TimeDisplayLabel.Text = '';
            app.Label_6.Text = 'α: --   β: --   γ: --   δ: --';
        
            % === CYBER STYLE COLORS ===
            neonGreen = [0.6 1 0.6];

            % === AXES STYLING ===
            app.BattleAxes.FontName = 'Courier New';
            app.BattleAxes.FontSize = 14;
            app.BattleAxes.Title.String = '⚔️ LIVE BATTLE: DEFENSE VS ATTACK';
            app.BattleAxes.XLabel.String = '🕒 Time (Hours)';
            app.BattleAxes.YLabel.String = '💾 Population';
        
            % === RUN BUTTON ===
            app.RunSimulationButton.BackgroundColor = [0.2 0.8 0.2];
            app.RunSimulationButton.FontWeight = 'bold';
            app.RunSimulationButton.FontName = 'Courier New';
            app.RunSimulationButton.Text = 'Run Simulation';
        
            % === NETWORK HEALTH LAMP LABEL ===
            app.NetworkHealthLampLabel.Text = 'Network Status';
            app.NetworkHealthLampLabel.FontWeight = 'bold';
        
            % === OUTCOME PANEL ===
            app.OutcomeSummaryPanel.Title = '📝 Debriefing Summary';
            app.OutcomeSummaryLabel.FontColor = neonGreen;
            app.OutcomeSummaryLabel.FontName = 'Courier New';
            app.OutcomeSummaryLabel.BackgroundColor = [0 0 0];
        
            % === TIME DISPLAY ===
            app.TimeDisplayLabel.FontName = 'Courier New';
            app.TimeDisplayLabel.FontSize = 18;
        
            % === Gauges ===
            app.DefendersGauge.ScaleColorLimits = [0 100];
            app.AttackersGauge.ScaleColorLimits = [0 100];
        
            % === Sliders + Labels ===
            app.DefenderGrowthRateSlider_2.FontName = 'Courier New';
            app.AttackerBurnoutRateSlider_2.FontName = 'Courier New';
      
            % === Simulation Panel ===
            app.Label_6.FontName = 'Courier New';
        
            % Table Styling 
            app.UITable.BackgroundColor = [0.8 0.9 1; 0.75 0.85 1];
            app.UITable.FontName = 'Courier New';
            app.UITable.FontSize = 11;
            app.UITable.FontWeight = 'bold';
        
             % 🔥 Add this mission briefing popup
            briefing = uifigure('Name', 'Defend the Network', 'Position', [100 100 600 520]);
            briefing.Color = [0.1 0.1 0.1];
        
            bg = [0.15 0.15 0.15];
            green = [0.2 1 0.2];
        
            uilabel(briefing, ...
                'Text', '🚨 CYBERSECURITY SIMULATION BRIEFING 🚨', ...
                'Position', [40 450 520 40], ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'FontColor', green, ...
                'HorizontalAlignment', 'center', ...
                'BackgroundColor', bg);
        
            uilabel(briefing, ...
                'Text', sprintf([ ...
                    'OBJECTIVE:\n' ...
                    'Simulate defending a network from sustained cyber attacks using strategic resources.\n\n' ...
                    'INTEL BRIEF:\n' ...
                    '🛡️ DEFENDERS — security systems, analysts, automation\n' ...
                    '💀 ATTACKERS — exploits, bots, adversaries in the wire\n\n' ...
                    'You will simulate multiple network attacks using the Lotka-Volterra predator-prey model.\n' ...
                    'You can adjust 4 key parameters:\n' ...
                    '  • α (Defender Growth)\n' ...
                    '  • β (Attack Effectiveness)\n' ...
                    '  • γ (Attacker Burnout)\n' ...
                    '  • δ (Defender Response Efficiency)\n\n' ...
                    'The system equilibrium marks a balance point: no clear winner, but constant pressure.\n\n' ...
                    'TOOLS:\n' ...
                    ' - Run simulations\n' ...
                    ' - View nullclines (mathematical balance lines)\n' ...
                    ' - Read outcome summaries based on attacker/defender dynamics\n\n' ...
                    'Ready the network for attacks. Choose the parameters wisely.\n\n']), ...
                'FontName', 'Courier New', ...
                'FontSize', 11, ...
                'FontColor', [0.9 1 0.9], ...
                'BackgroundColor', bg, ...
                'Position', [40 70 520 390], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'WordWrap', 'on');
        
            uibutton(briefing, ...
                'Text', 'Simulation →', ...
                'Position', [230 20 150 30], ...
                'FontWeight', 'bold', ...
                'FontColor', 'black', ...
                'BackgroundColor', [0.3 1 0.3], ...
                'ButtonPushedFcn', @(src, event) close(briefing));

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
                % Initial populations
                x0 = 9;   % Attackers
                y0 = 40;  % Defenders
                tspan = [0 50];

                % Slider values normalized to [0, 1]
                alpha = app.DefenderGrowthRateSlider_2.Value / 100;  % Defender growth
                gamma = app.AttackerBurnoutRateSlider_2.Value / 100; % Attacker decay

                % Attacker behavior based on difficulty
                [beta, delta] = src.attacker_ai(app.DifficultyDropDown.Value);

                % Update the Live Parameter Label text
                app.Label_6.Text = sprintf( ...
                    'α: %.2f   β: %.2f   γ: %.2f   δ: %.2f', ...
                    alpha, beta, gamma, delta);

                % Lotka-Volterra simulation
                [t, pop] = src.simulate_lv_dynamics([x0; y0], tspan, alpha, beta, delta, gamma);

                % Precompute differentials
                dx = alpha .* pop(:,1) - beta .* pop(:,1) .* pop(:,2);   % Δx
                dy = delta .* pop(:,1) .* pop(:,2) - gamma .* pop(:,2);  % Δy

                % Table update
                rowsToShow = min(50, length(t));
                tableData = [pop(1:rowsToShow,1), pop(1:rowsToShow,2), dx(1:rowsToShow), dy(1:rowsToShow)];
                app.UITable.Data = round(tableData, 2);

                % Clear and configure axes
                cla(app.BattleAxes);
                hold(app.BattleAxes, 'on');
                app.BattleAxes.XLim = [min(t), max(t)];
                roundedMax = ceil(max(max(pop)) / 20) * 20;
                app.BattleAxes.YLim = [0, roundedMax];
                app.BattleAxes.XTick = linspace(min(t), max(t), 6);
                app.BattleAxes.YTick = linspace(0, roundedMax, 6);

                hDef = animatedline(app.BattleAxes, 'Color', 'b', 'LineWidth', 2);
                hAtk = animatedline(app.BattleAxes, 'Color', 'r', 'LineWidth', 2);

                % Animate simulation
                for i = 1:length(t)
                    addpoints(hDef, t(i), pop(i,2));
                    addpoints(hAtk, t(i), pop(i,1));

                    app.DefendersGauge.Value = min(pop(i,2), 100);
                    app.AttackersGauge.Value = min(pop(i,1), 100);

                    % Update stats
                    app.DefenderStatsLabel.Text = sprintf('Mean: %.1f  |  Final: %.1f', mean(pop(1:i,2)), pop(i,2));
                    app.AttackerStatsLabel.Text = sprintf('Mean: %.1f  |  Final: %.1f', mean(pop(1:i,1)), pop(i,1));

                    % Status during simulation
                    if pop(i,2) > pop(i,1) * 1.5
                        liveStatus = 'Status: Holding';
                        liveColor = [0.6 1 0.6];
                    elseif pop(i,1) > pop(i,2) * 1.5
                        liveStatus = 'Status: Under Attack';
                        liveColor = [1 0.5 0.5];
                    else
                        liveStatus = 'Status: Contested';
                        liveColor = [1 1 0.5];
                    end

                    app.NetworkHealthLamp.Color = liveColor;
                    app.NetworkSecureLabel.Text = sprintf('%s\nFinal: Pending...', liveStatus);
                    app.TimeDisplayLabel.Text = sprintf('Time: %.1f', t(i));

                    drawnow;
                    pause(0.05);
                end

                % Final status evaluation
                [finalLiveStatus, finalStatusRaw, finalStatusEmoji, lampColor] = src.health_status(pop);
    
                app.NetworkHealthLamp.Color = lampColor;
                app.NetworkSecureLabel.Text = sprintf('%s\n%s', finalLiveStatus, finalStatusEmoji);
    
                % Generate summary
                switch finalStatusRaw
                    case 'Secure'
                        app.OutcomeSummaryLabel.Text = '“You can’t hack what you can’t touch.” — The attackers broke like interns on a Friday. Your defenses held firm, your team stayed frosty, and the system integrity is still squeaky clean. Go ahead and pat that SOC on the back.';
                    case 'Breached'
                        app.OutcomeSummaryLabel.Text = '“They’re inside the house!” — Despite valiant efforts, the attackers slipped past the firewall, danced around your EDR, and helped themselves to your crown jewels. Consider rotating credentials and maybe lighting some incense.';  
                    case 'Contested'
                        app.OutcomeSummaryLabel.Text = '“It’s quiet… too quiet.” — Neither side could land a decisive blow. Logs are a mess, alerts are flying, and the SIEM is too stunned to respond. A classic standoff. Adjust your parameters and prep for the next attack.';
                    otherwise
                        app.OutcomeSummaryLabel.Text = 'Unknown outcome — the logs are missing, and no one’s owning up.';
                end
    
                % Show nullclines and equilibrium if checkbox is enabled
                if app.ShowNullclinesCheckBox.Value
                    drawNullclines(app, alpha, beta, gamma, delta);
                end
    
                % Finalize plot
                legend(app.BattleAxes, {'Defenders', 'Attackers'}, 'Location', 'northeast');
                title(app.BattleAxes, 'Cyber Battle: Defenders vs. Attackers');
                xlabel(app.BattleAxes, 'Time (Hours)');
                ylabel(app.BattleAxes, 'Population (Attackers/Defenders)');
                hold(app.BattleAxes, 'off');
        end

        % Button pushed function: LearnMoreButton
        function LearnMoreButtonPushed(app, event)
            d = uifigure('Name', 'About This Simulation', 'Position', [100 100 480 400]);
            d.Color = [0.98 0.98 0.95];  % Light cream background

            msg = sprintf([ ...
                'This simulation models a cybersecurity battle using predator-prey dynamics.\n\n', ...
                '🛡️ Defenders: firewalls, analysts, detection systems\n', ...
                '💀 Attackers: threats, exploits, malware\n\n', ...
                'The equations reflect:\n', ...
                '⚔️ Growth, burnout, and interaction between forces\n\n', ...
                'dx/dt = αx - βxy → attackers increase, then decline when defenders push back\n', ...
                'dy/dt = δxy - γy → defenders strengthen when active, but can wear out\n\n', ...
                '🧮 Equilibrium Point:\n', ...
                'Occurs where both dx/dt and dy/dt are zero.\n', ...
                'If the attacker and defender levels land here, both forces stay balanced.\n', ...
                'This is your system''s neutral zone — where neither side gains ground.\n\n', ...
                '📊 Use the "Show Nullclines" checkbox to view the dx/dt=0 and dy/dt=0 lines,\n', ...
                'as well as the equilibrium marker on the battlefield chart.' ...
                'Tweak parameters to test different strategies!' ...
                ]);

            uilabel(d, 'Text', msg, ...
                'Position', [20 60 440 320], ...
                'WordWrap', 'on', 'FontSize', 11);

            uibutton(d, 'Text', 'Close', ...
                'Position', [195, 20, 90, 30], ...
                'ButtonPushedFcn', @(src,event) close(d));
        end

        % Value changed function: PresetScenariosDropDown
        function PresetScenariosDropDownValueChanged(app, event)
            selectedPreset = app.PresetScenariosDropDown.Value;

            switch selectedPreset
                case 'Custom 🧰'
                    % Leave sliders as-is, allow user to tweak freely
                return;

                case 'Normal Day 🛡️'
                    app.Label_5.Text = 'Low threat activity. Great for observing stable defender strategies.';
                    app.DifficultyDropDown.Value = 'Easy';
                    app.DefenderGrowthRateSlider_2.Value = 80; % α = 0.8
                    app.AttackerBurnoutRateSlider_2.Value = 70; % γ = 0.7

                case 'Zero-Day Frenzy 💀'
                    app.Label_5.Text = 'Simulates a high-speed exploit storm. Defenders are overwhelmed quickly.';
                    app.DifficultyDropDown.Value = 'Hard';
                    app.DefenderGrowthRateSlider_2.Value = 40; % α = 0.4
                    app.AttackerBurnoutRateSlider_2.Value = 30; % γ = 0.3

                case 'Persistent Threat ⚠️'
                    app.Label_5.Text = 'Advanced attackers probe continuously. Balance and endurance are key.';
                    app.DifficultyDropDown.Value = 'Medium';
                    app.DefenderGrowthRateSlider_2.Value = 60; % α = 0.6
                    app.AttackerBurnoutRateSlider_2.Value = 40; % γ = 0.4

                    % Add more cases here if needed
           end
        end

        % Value changed function: DifficultyDropDown
        function DifficultyDropDownValueChanged(app, event)
            value = app.DifficultyDropDown.Value;
            switch value
                case 'Easy'
                    app.Label.Text = 'Low threat activity. Balanced environment for defenders.';
                case 'Medium'
                    app.Label.Text = 'Active threat probing. Balanced challenge on both sides.';
                case 'Hard'
                    app.Label.Text = 'Aggressive attacker behavior. Defenders are heavily stressed.';
                otherwise
                    app.Label.Text = 'Difficulty adjusts attacker behavior.';
            end
        end

        % Value changed function: ShowNullclinesCheckBox
        function ShowNullclinesCheckBoxValueChanged(app, event)
            show = app.ShowNullclinesCheckBox.Value;
        
            % Delete previous lines and markers if they exist
            if isgraphics(app.NullclineXLine), delete(app.NullclineXLine); end
            if isgraphics(app.NullclineYLine), delete(app.NullclineYLine); end
            if isgraphics(app.EquilibriumMarker), delete(app.EquilibriumMarker); end
            if isgraphics(app.NullclinePopupText), delete(app.NullclinePopupText); end
        
            if show
                % Recalculate parameters
                alpha = app.DefenderGrowthRateSlider_2.Value / 100;
                gamma = app.AttackerBurnoutRateSlider_2.Value / 100;
                [beta, delta] = src.attacker_ai(app.DifficultyDropDown.Value);
        
                % Calculate nullcline coordinates
                y_null = alpha / beta;
                x_null = gamma / delta;
        
                % Preserve current axis limits and hold state
                hold(app.BattleAxes, 'on');
        
                x_range = app.BattleAxes.XLim;
                y_range = app.BattleAxes.YLim;
        
                % Draw nullclines
                app.NullclineXLine = plot(app.BattleAxes, x_range, [y_null y_null], ...
                    '--', 'Color', [0.1 0.6 0.1], 'LineWidth', 1.5, 'DisplayName', 'dy/dt = 0');
        
                app.NullclineYLine = plot(app.BattleAxes, [x_null x_null], y_range, ...
                    '--', 'Color', [0.3 0.3 0.9], 'LineWidth', 1.5, 'DisplayName', 'dx/dt = 0');
        
                % Draw equilibrium point
                app.EquilibriumMarker = plot(app.BattleAxes, x_null, y_null, ...
                    'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 7, 'DisplayName', 'Equilibrium');
        
                % Popup text annotation
                app.NullclinePopupText = text(app.BattleAxes, x_null + 1, y_null + 1, ...
                    'Equilibrium Point', 'FontSize', 9, 'BackgroundColor', 'w', ...
                    'Margin', 4, 'EdgeColor', [0.4 0.4 0.4]);
        
                hold(app.BattleAxes, 'off');
            end           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {345, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DifficultyDropDownLabel = uilabel(app.LeftPanel);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.LeftPanel);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.ValueChangedFcn = createCallbackFcn(app, @DifficultyDropDownValueChanged, true);
            app.DifficultyDropDown.Tooltip = {'Controls attacker behavior: harder levels increase attack speed and decrease defender advantage.'};
            app.DifficultyDropDown.Value = 'Easy';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation →';
            app.DefenderGrowthRateSlider_2Label = uilabel(app.LeftPanel);
            app.DefenderGrowthRateSlider_2Label.Text = 'Defender Growth Rate (α)';
            app.DefenderGrowthRateSlider_2 = uislider(app.LeftPanel);
            app.DefenderGrowthRateSlider_2.Tooltip = {'How fast defenders build up resources or strengthen security measures.'};
            app.AttackerBurnoutRateSlider_2Label = uilabel(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2Label.Text = 'Attacker Burnout Rate (γ)';
            app.AttackerBurnoutRateSlider_2 = uislider(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2.Tooltip = {'How quickly attackers lose steam or get blocked if they’re not successful.'};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Difficulty adjusts how aggressive attackers are. Hard = faster attackers and greater defender stress.';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'ATK (x)'; 'DEF (y)'; '   Δx'; 'Δy'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Higher values = defenders recover faster and grow more rapidly.';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'Higher values = attackers tire out quickly or fail faster.';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'Table shows attacker/defender populations and the change rates per step.';
            app.PresetScenariosDropDownLabel = uilabel(app.LeftPanel);
            app.PresetScenariosDropDownLabel.Text = 'Preset Scenarios';
            app.PresetScenariosDropDown = uidropdown(app.LeftPanel);
            app.PresetScenariosDropDown.Items = {'Custom 🧰', 'Normal Day 🛡️', 'Zero-Day Frenzy 💀', 'Persistent Threat ⚠️'};
            app.PresetScenariosDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetScenariosDropDownValueChanged, true);
            app.PresetScenariosDropDown.Tooltip = {'Choose a preset to auto-fill parameters for common cyber-attack dynamics.'};
            app.PresetScenariosDropDown.Value = 'Custom 🧰';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = 'Presets configure difficulty and strategy levels to simulate real-world scenarios.';
            app.SimulationParametersPanel = uipanel(app.LeftPanel);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.Label_6 = uilabel(app.SimulationParametersPanel);
            app.Label_6.Text = 'α: --   β: --   γ: --   δ: --';
            app.ShowNullclinesCheckBox = uicheckbox(app.LeftPanel);
            app.ShowNullclinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNullclinesCheckBoxValueChanged, true);
            app.ShowNullclinesCheckBox.Text = 'Show Nullclines & Equilibrium';
            app.RightPanel = uipanel(app.GridLayout);
            app.BattleAxes = uiaxes(app.RightPanel);
            title(app.BattleAxes, 'Cyber Battle: Defender vs Attacker')
            xlabel(app.BattleAxes, 'Time (Hours)')
            app.BattleAxes.XTick = [0 2 4 6 8 10 12 24 36 48];
            app.BattleAxes.YTick = [0 20 40 60 80 100];
            app.DefendersGaugeLabel = uilabel(app.RightPanel);
            app.DefendersGaugeLabel.Text = 'Defenders';
            app.DefendersGauge = uigauge(app.RightPanel, 'semicircular');
            app.AttackersGaugeLabel = uilabel(app.RightPanel);
            app.AttackersGaugeLabel.Text = 'Attackers';
            app.AttackersGauge = uigauge(app.RightPanel, 'semicircular');
            app.TimeDisplayLabel = uilabel(app.RightPanel);
            app.TimeDisplayLabel.Text = 'Time: 0.0';
            app.NetworkHealthLampLabel = uilabel(app.RightPanel);
            app.NetworkHealthLampLabel.Text = 'Network Health';
            app.NetworkHealthLamp = uilamp(app.RightPanel);
            app.NetworkSecureLabel = uilabel(app.RightPanel);
            app.NetworkSecureLabel.Text = 'Network Secure';
            app.LearnMoreButton = uibutton(app.RightPanel, 'push');
            app.LearnMoreButton.ButtonPushedFcn = createCallbackFcn(app, @LearnMoreButtonPushed, true);
            app.LearnMoreButton.Text = 'Learn More ℹ️ ';
            app.DefenderStatsLabel = uilabel(app.RightPanel);
            app.DefenderStatsLabel.Text = 'DefenderStats';
            app.AttackerStatsLabel = uilabel(app.RightPanel);
            app.AttackerStatsLabel.Text = 'AttackerStats';
            app.OutcomeSummaryPanel = uipanel(app.RightPanel);
            app.OutcomeSummaryPanel.Title = 'Final Outcome Summary';
            app.OutcomeSummaryLabel = uilabel(app.OutcomeSummaryPanel);
            app.OutcomeSummaryLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefendTheNetwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end